classdef Final_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        HazardInputPanel                matlab.ui.container.Panel
        OrderofFittingPolynomialSwitchLabel  matlab.ui.control.Label
        OrderofFittingPolynomialSwitch  matlab.ui.control.RockerSwitch
        FileUploadTextAreaLabel         matlab.ui.control.Label
        FileUploadTextArea              matlab.ui.control.TextArea
        LoadHazardCurveButton           matlab.ui.control.Button
        LowerHazardRange                matlab.ui.control.TextArea
        UpperHazardRange                matlab.ui.control.TextArea
        LowerIntegrationRange           matlab.ui.control.TextArea
        UpperIntegrationRange           matlab.ui.control.TextArea
        toLabel_2                       matlab.ui.control.Label
        toLabel_3                       matlab.ui.control.Label
        HazardFitRangeLabel             matlab.ui.control.Label
        SaIntegrationRangeLabel         matlab.ui.control.Label
        StructureInputPanel             matlab.ui.container.Panel
        LoadStructureDataButton         matlab.ui.control.Button
        FileUploadTextArea_2Label       matlab.ui.control.Label
        FileUploadTextArea_2            matlab.ui.control.TextArea
        LoadStructureResponseButton     matlab.ui.control.Button
        FileUploadTextArea_3Label       matlab.ui.control.Label
        FileUploadTextArea_3            matlab.ui.control.TextArea
        FragilitiesandLossPanel         matlab.ui.container.Panel
        FileUploadTextArea_4Label       matlab.ui.control.Label
        FileUploadTextArea_4            matlab.ui.control.TextArea
        LoadFragilityLossDataButton     matlab.ui.control.Button
        DemolitionParametersPanel       matlab.ui.container.Panel
        MedianRIDRTextAreaLabel         matlab.ui.control.Label
        MedianRIDR                      matlab.ui.control.TextArea
        DispersionRIDRTextAreaLabel     matlab.ui.control.Label
        DispersionRIDR                  matlab.ui.control.TextArea
        RunAnalysisButton               matlab.ui.control.Button
        HazardTab                       matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        FragilityDisplay_4              matlab.ui.container.Panel
        polyOrder                       matlab.ui.control.Label
        FragilityDisplay_5              matlab.ui.container.Panel
        fittingRange                    matlab.ui.control.Label
        FragilityDisplay_6              matlab.ui.container.Panel
        integrationRange                matlab.ui.control.Label
        StructuralResponseTab           matlab.ui.container.Tab
        EDPPlotOptionDropDownLabel      matlab.ui.control.Label
        EDPPlotOptionDropDown           matlab.ui.control.DropDown
        UIAxes5                         matlab.ui.control.UIAxes
        CollapseFragilityTab            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FragilityDisplay                matlab.ui.container.Panel
        CFragilityMean                  matlab.ui.control.Label
        FragilityDisplay_2              matlab.ui.container.Panel
        CFragilityDispersion            matlab.ui.control.Label
        FragilityDisplay_3              matlab.ui.container.Panel
        CFragilityPC50                  matlab.ui.control.Label
        FragilityDisplay_7              matlab.ui.container.Panel
        MAF50                           matlab.ui.control.Label
        DamageFragilitiesTab            matlab.ui.container.Tab
        ComponentDropDownLabel          matlab.ui.control.Label
        ComponentDropDown               matlab.ui.control.DropDown
        UIAxes3                         matlab.ui.control.UIAxes
        LossEstimationTab               matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        AverageAnnualLossPanel          matlab.ui.container.Panel
        AAL                             matlab.ui.control.Label
        LossDeaggregationPanel          matlab.ui.container.Panel
        DNELabel_4                      matlab.ui.control.Label
        DNELabel_5                      matlab.ui.control.Label
        DNELabel_6                      matlab.ui.control.Label
        CollapseDeaggregation           matlab.ui.control.Label
        DemoAggregation                 matlab.ui.control.Label
        RepairAggregation               matlab.ui.control.Label
    end


    properties (Access = public)
        handles % Where literally everything is stored
        hazardFile
    end


    methods (Access = private)

        % Button pushed function: LoadStructureDataButton
        function LoadStructure(app, event)
            app.handles.structureFile = uigetfile('*.*');
             if isequal(app.handles.structureFile, 0)
                 disp('User select Cancel')
             else
                 app.FileUploadTextArea_2.Value = app.handles.structureFile;
             end             
        end

        % Button pushed function: LoadStructureResponseButton
        function LoadResponse(app, event)
             app.handles.responseFiles = uigetfile('*.csv', 'Select all Files', 'MultiSelect', 'on');
             if isequal(app.handles.responseFiles, 0)
                 app.FileUploadTextArea_3.Value = 'User Selection Cancelled';
             else
                 app.FileUploadTextArea_3.Value = app.handles.responseFiles;
             end
            
            
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysis(app, event)
            fig = uifigure;
            fig.Name = 'PHSA Structure Application';
            
            % Load in the hazard curve
            app.getPolynomialOrder();
            app.handles.hazard = load(app.hazardFile);
            l = str2double(app.LowerHazardRange.Value);
            u = str2double(app.UpperHazardRange.Value);
            app.handles.interval = [l, u];
            l = str2double(app.LowerIntegrationRange.Value);
            u = str2double(app.UpperIntegrationRange.Value);
            app.handles.SaIntegration = [l, u];
            
            [app]= createPolyFit(app);
            app.plotPolyFit();        
            
            % Initialize range of variables for integration
            app.handles.EDP.IDR = 0.0001:.0001:.15;
            app.handles.EDP.PFA = 0.001:.005:4.0;
            app.handles.EDP.RIDR = 0.0001:.0001:.1;
            
            % GUI Inputs
            
            ns = length(app.handles.responseFiles); %ns - number of stripes
            file = size(readtable(app.handles.responseFiles{1})); % Can we get this from loading file?
            n = file(2)-1; %n - number of GM
          
            
            filenames = app.handles.responseFiles;
            app.handles.stripes = parseStripes(app, filenames);
            app = LoadStripeData(app, filenames, ns);
            app = ResponseEstimation(app);
            
            app.EDPPlotOptionDropDown.Items = app.handles.EDPnames;
            
            app = CollapseFragility(n, app);
            app.CFragilityMean.Text = strcat(num2str(app.handles.CollapseMedian), ' g');
            app.CFragilityDispersion.Text = num2str(app.handles.CollapseStd);
            app.CFragilityPC50.Text = num2str(app.handles.Prob_50);
            app.MAF50.Text = num2str(app.handles.MAF_c);
            plotCollapse(n, app)
            
            filename = app.handles.fragilitylossFile;
            app = loadComputeDamageFragilities(app, filename);
            app.ComponentDropDown.Items = app.handles.Components;
            
            filename = app.handles.structureFile;
            app = loadStructure(filename, app);
            
            % Return expected loss given EDP conditioned on no collapse and no repair
            app = expectedLoss_EDP(app);
            
            % Return expected loss given IM conditioned on no collapse and no repair
            app = expectedLoss_IM(app);
            
            % Probability of Demolition Given No Collapse
            app = ExpectedLoss(app);
            
            % Find probabilities of all 3 ingredients (repair, demo, collapse) and plot
            % all loss functions given IM
            app = repairCollapseDemoProb(app);
            plotLossDeaggregation(app)
            
            % Calculate the AAL and deaggregation ratios (could be used for fun things)
            app = averageAnnualLoss(app);
            
            app.AAL = strcat('$ ', num2str(app.handles.AAL));
            app.CollapseDeaggregation.Text = strcat('$', num2str(app.handles.AAL_collapse), ' (%)', num2str(round(app.handles.ratio.Collapse , 3)*100));
            app.DemoAggregation.Text = strcat('$', num2str(app.handles.AAL_demo), ' (%)', num2str(round(app.handles.ratio.Demo , 3)*100));
            app.RepairAggregation.Text = strcat('$', num2str(app.handles.AAL_repair), ' (%)', num2str(round(app.handles.ratio.Repair , 3)*100));
        end

        % Button pushed function: LoadFragilityLossDataButton
        function LoadFragilityLoss(app, event)
             app.handles.fragilitylossFile = uigetfile('*.csv');
             if isequal(app.handles.fragilitylossFile, 0)
                 app.FileUploadTextArea_4.Value = 'User Selected Cancel';
             else
                 app.FileUploadTextArea_4.Value = app.handles.fragilitylossFile;
             end
        end

        % Value changed function: EDPPlotOptionDropDown
        function EDPmedianResponse(app, event)
            value = app.EDPPlotOptionDropDown.Value;
            plotEDPresponse(app,value);
        end

        % Value changed function: ComponentDropDown
        function fragilityPlot(app, event)
            value = app.ComponentDropDown.Value;
            plotFragilities(app,value);
        end

        % Menu selected function: QuitMenu
        function Quit(app, event)
             closereq
        end

        % Button pushed function: LoadHazardCurveButton
        function LoadHazardCurve(app, event)
             app.hazardFile = uigetfile('*.txt', '*.csv');
             if isequal(app.hazardFile, 0)
                 app.FileUploadTextArea.Value = 'User Selected Cancel';
             else
                 app.FileUploadTextArea.Value = app.hazardFile;
             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @Quit, true);
            app.QuitMenu.Accelerator = 'q';
            app.QuitMenu.Text = 'Quit';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.HazardInputPanel = uipanel(app.InputsTab);
            app.HazardInputPanel.Title = 'Hazard Input';
            app.OrderofFittingPolynomialSwitchLabel = uilabel(app.HazardInputPanel);
            app.OrderofFittingPolynomialSwitchLabel.Text = 'Order of Fitting Polynomial';
            app.OrderofFittingPolynomialSwitch = uiswitch(app.HazardInputPanel, 'rocker');
            app.OrderofFittingPolynomialSwitch.Items = {'3rd', '4th'};
            app.OrderofFittingPolynomialSwitch.Value = '3rd';
            app.FileUploadTextAreaLabel = uilabel(app.HazardInputPanel);
            app.FileUploadTextAreaLabel.Text = 'File Upload:';
            app.FileUploadTextArea = uitextarea(app.HazardInputPanel);
            app.FileUploadTextArea.Editable = 'off';
            app.FileUploadTextArea.Value = {'Please Load Hazard'};
            app.LoadHazardCurveButton = uibutton(app.HazardInputPanel, 'push');
            app.LoadHazardCurveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadHazardCurve, true);
            app.LoadHazardCurveButton.Text = 'Load Hazard Curve';
            app.LowerHazardRange = uitextarea(app.HazardInputPanel);
            app.LowerHazardRange.Value = {'0.01'};
            app.UpperHazardRange = uitextarea(app.HazardInputPanel);
            app.UpperHazardRange.Value = {'2'};
            app.LowerIntegrationRange = uitextarea(app.HazardInputPanel);
            app.LowerIntegrationRange.Value = {'0.01'};
            app.UpperIntegrationRange = uitextarea(app.HazardInputPanel);
            app.UpperIntegrationRange.Value = {'3'};
            app.toLabel_2 = uilabel(app.HazardInputPanel);
            app.toLabel_2.Text = 'to';
            app.toLabel_3 = uilabel(app.HazardInputPanel);
            app.toLabel_3.Text = 'to';
            app.HazardFitRangeLabel = uilabel(app.HazardInputPanel);
            app.HazardFitRangeLabel.Text = 'Hazard Fit Range:';
            app.SaIntegrationRangeLabel = uilabel(app.HazardInputPanel);
            app.SaIntegrationRangeLabel.Text = 'Sa Integration Range:';
            app.StructureInputPanel = uipanel(app.InputsTab);
            app.StructureInputPanel.Title = 'Structure Input';
            app.LoadStructureDataButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStructure, true);
            app.LoadStructureDataButton.Text = 'Load Structure Data';
            app.FileUploadTextArea_2Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_2Label.Text = 'File Upload:';
            app.FileUploadTextArea_2 = uitextarea(app.StructureInputPanel);
            app.FileUploadTextArea_2.Value = {'Please Load Structure'};
            app.LoadStructureResponseButton = uibutton(app.StructureInputPanel, 'push');
            app.LoadStructureResponseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResponse, true);
            app.LoadStructureResponseButton.Text = 'Load Structure Response';
            app.FileUploadTextArea_3Label = uilabel(app.StructureInputPanel);
            app.FileUploadTextArea_3Label.Text = 'File Upload:';
            app.FileUploadTextArea_3 = uitextarea(app.StructureInputPanel);
            app.FileUploadTextArea_3.Value = {'Please Load Response'};
            app.FragilitiesandLossPanel = uipanel(app.InputsTab);
            app.FragilitiesandLossPanel.Title = 'Fragilities and Loss';
            app.FileUploadTextArea_4Label = uilabel(app.FragilitiesandLossPanel);
            app.FileUploadTextArea_4Label.Text = 'File Upload:';
            app.FileUploadTextArea_4 = uitextarea(app.FragilitiesandLossPanel);
            app.FileUploadTextArea_4.Editable = 'off';
            app.FileUploadTextArea_4.Value = {'Please Load Fragilities'};
            app.LoadFragilityLossDataButton = uibutton(app.FragilitiesandLossPanel, 'push');
            app.LoadFragilityLossDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFragilityLoss, true);
            app.LoadFragilityLossDataButton.Text = 'Load Fragility/Loss Data';
            app.DemolitionParametersPanel = uipanel(app.InputsTab);
            app.DemolitionParametersPanel.Title = 'Demolition Parameters:';
            app.MedianRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.MedianRIDRTextAreaLabel.Text = 'Median RIDR:';
            app.MedianRIDR = uitextarea(app.DemolitionParametersPanel);
            app.MedianRIDR.Value = {'0.015'};
            app.DispersionRIDRTextAreaLabel = uilabel(app.DemolitionParametersPanel);
            app.DispersionRIDRTextAreaLabel.Text = 'Dispersion RIDR:';
            app.DispersionRIDR = uitextarea(app.DemolitionParametersPanel);
            app.DispersionRIDR.Value = {'0.3'};
            app.RunAnalysisButton = uibutton(app.InputsTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysis, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.HazardTab = uitab(app.TabGroup);
            app.HazardTab.Title = 'Hazard';
            app.UIAxes2 = uiaxes(app.HazardTab);
            title(app.UIAxes2, 'Hazard Plot')
            xlabel(app.UIAxes2, 'Sa (g)')
            ylabel(app.UIAxes2, 'MAF (1/yr)')
            app.FragilityDisplay_4 = uipanel(app.HazardTab);
            app.FragilityDisplay_4.Title = 'Polynomial Order';
            app.polyOrder = uilabel(app.FragilityDisplay_4);
            app.polyOrder.Text = '-';
            app.FragilityDisplay_5 = uipanel(app.HazardTab);
            app.FragilityDisplay_5.Title = 'Fitting Range';
            app.fittingRange = uilabel(app.FragilityDisplay_5);
            app.fittingRange.Text = '-';
            app.FragilityDisplay_6 = uipanel(app.HazardTab);
            app.FragilityDisplay_6.Title = 'Integration Range';
            app.integrationRange = uilabel(app.FragilityDisplay_6);
            app.integrationRange.Text = '-';
            app.StructuralResponseTab = uitab(app.TabGroup);
            app.StructuralResponseTab.Title = 'Structural Response';
            app.EDPPlotOptionDropDownLabel = uilabel(app.StructuralResponseTab);
            app.EDPPlotOptionDropDownLabel.Text = 'EDP Plot Option:';
            app.EDPPlotOptionDropDown = uidropdown(app.StructuralResponseTab);
            app.EDPPlotOptionDropDown.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.EDPPlotOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @EDPmedianResponse, true);
            app.EDPPlotOptionDropDown.Value = 'None';
            app.UIAxes5 = uiaxes(app.StructuralResponseTab);
            title(app.UIAxes5, 'EDP Response Plot')
            xlabel(app.UIAxes5, 'IM')
            ylabel(app.UIAxes5, 'EDP')
            app.CollapseFragilityTab = uitab(app.TabGroup);
            app.CollapseFragilityTab.Title = 'Collapse Fragility';
            app.UIAxes = uiaxes(app.CollapseFragilityTab);
            title(app.UIAxes, 'Collapse Fragility')
            xlabel(app.UIAxes, 'IM')
            ylabel(app.UIAxes, 'P(C | IM)')
            app.FragilityDisplay = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay.Title = 'Collapse Fragility Median';
            app.CFragilityMean = uilabel(app.FragilityDisplay);
            app.CFragilityMean.Text = '-';
            app.FragilityDisplay_2 = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay_2.Title = 'Collapse Fragility Dispersion';
            app.CFragilityDispersion = uilabel(app.FragilityDisplay_2);
            app.CFragilityDispersion.Text = '-';
            app.FragilityDisplay_3 = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay_3.Title = 'P(C) in 50 yr.';
            app.CFragilityPC50 = uilabel(app.FragilityDisplay_3);
            app.CFragilityPC50.Text = '-';
            app.FragilityDisplay_7 = uipanel(app.CollapseFragilityTab);
            app.FragilityDisplay_7.Title = 'MAF 50 yr.';
            app.MAF50 = uilabel(app.FragilityDisplay_7);
            app.MAF50.Text = '-';
            app.DamageFragilitiesTab = uitab(app.TabGroup);
            app.DamageFragilitiesTab.Title = 'Damage Fragilities';
            app.ComponentDropDownLabel = uilabel(app.DamageFragilitiesTab);
            app.ComponentDropDownLabel.Text = 'Component';
            app.ComponentDropDown = uidropdown(app.DamageFragilitiesTab);
            app.ComponentDropDown.Items = {'None', 'Option 2', 'Option 3', 'Option 4'};
            app.ComponentDropDown.ValueChangedFcn = createCallbackFcn(app, @fragilityPlot, true);
            app.ComponentDropDown.Value = 'None';
            app.UIAxes3 = uiaxes(app.DamageFragilitiesTab);
            title(app.UIAxes3, 'Damage Fragility')
            xlabel(app.UIAxes3, 'EDP')
            ylabel(app.UIAxes3, 'P(DS > ds | EDP)')
            app.LossEstimationTab = uitab(app.TabGroup);
            app.LossEstimationTab.Title = 'Loss Estimation';
            app.UIAxes4 = uiaxes(app.LossEstimationTab);
            title(app.UIAxes4, 'Loss Given IM')
            xlabel(app.UIAxes4, 'Sa (g)')
            ylabel(app.UIAxes4, 'Expected Loss ($)')
            app.AverageAnnualLossPanel = uipanel(app.LossEstimationTab);
            app.AverageAnnualLossPanel.Title = 'Average Annual Loss';
            app.AAL = uilabel(app.AverageAnnualLossPanel);
            app.AAL.Text = '$0';
            app.LossDeaggregationPanel = uipanel(app.LossEstimationTab);
            app.LossDeaggregationPanel.Title = 'Loss Deaggregation';
            app.DNELabel_4 = uilabel(app.LossDeaggregationPanel);
            app.DNELabel_4.Text = 'Collapse:';
            app.DNELabel_5 = uilabel(app.LossDeaggregationPanel);
            app.DNELabel_5.Text = 'Demo:';
            app.DNELabel_6 = uilabel(app.LossDeaggregationPanel);
            app.DNELabel_6.Text = 'Repair:';
            app.CollapseDeaggregation = uilabel(app.LossDeaggregationPanel);
            app.CollapseDeaggregation.Text = '$0 (0.0%)';
            app.DemoAggregation = uilabel(app.LossDeaggregationPanel);
            app.DemoAggregation.Text = '$0 (0.0%)';
            app.RepairAggregation = uilabel(app.LossDeaggregationPanel);
            app.RepairAggregation.Text = '$0 (0.0%)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Final_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end